classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        SolvingEquationusingNumericalMethodsLabel  matlab.ui.control.Label
        BrowseFileButton              matlab.ui.control.Button
        Label                         matlab.ui.control.Label
        GoButton                      matlab.ui.control.Button
        OutputScreenLabel             matlab.ui.control.Label
        EquationEditFieldLabel        matlab.ui.control.Label
        EquationEditField             matlab.ui.control.EditField
        MaxiterationsEditFieldLabel   matlab.ui.control.Label
        MaxiterationsEditField        matlab.ui.control.NumericEditField
        EpsilonEditFieldLabel         matlab.ui.control.Label
        TextArea                      matlab.ui.control.TextArea
        EpsilonEditField              matlab.ui.control.NumericEditField
        FiledirectoryEditFieldLabel   matlab.ui.control.Label
        FiledirectoryEditField        matlab.ui.control.EditField
        MethodSelectionDropDownLabel  matlab.ui.control.Label
        MethodSelectionDropDown       matlab.ui.control.DropDown
        MethodSelectedPanel           matlab.ui.container.Panel
        XLEditFieldLabel              matlab.ui.control.Label
        XLEditField                   matlab.ui.control.NumericEditField
        XUEditFieldLabel              matlab.ui.control.Label
        XUEditField                   matlab.ui.control.NumericEditField
        IntialGuessEditFieldLabel     matlab.ui.control.Label
        IntialGuessEditField          matlab.ui.control.EditField
        ClearButton                   matlab.ui.control.Button
    end

    
    properties (Access = private)
        Equations % Holder for equations
    end
    
    methods (Access = private)
        
        function result = FormulateProcess(app)
            if app.EquationEditField.Value == ""
                result = [];
            else
                str = app.EquationEditField.Value;
                result = split(str,",");
            end
        end
        
        function [root,precision,excecutionTime,iteration, status] = Bisection(app, equstr, XL, XU, es, iterations)
            app.TextArea.FontColor = [0.00,0.00,0.00];
            app.TextArea.Value = ["Equation: " equstr "iterations    XL  XU  XR  F(XR)   es"];
            tstart = cputime;
            equ = str2sym(equstr);
            fxl = subs(equ,XL);
            fxu = subs(equ,XU);
            XrOld = 0;
            if (fxl * fxu < 0)
                iteration = 1;
                error = 99999999;
                while (iteration <= iterations && es < error)
                    data = app.TextArea.Value;
                    
                    Xrnew = (XL + XU)/2;
                    error = abs((Xrnew - XrOld)/Xrnew);
                    fxr = subs(equ,Xrnew);
                    if (fxr < 0)
                        XL = Xrnew;
                    else
                        XU = Xrnew;
                    end
                    XrOld = Xrnew;
                    
                    disp(data);
                    disp(num2str([iteration XL XU XR F(XR) es]));
                    newData = [data(:) ; [iteration XL XU Xrnew fxr error]];
                    app.TextArea.Value = newData;
                    iteration = iteration + 1;
                end
                root = Xrnew;
                precision = floor(2 - log10(2 * error));
                excecutionTime = cputime - tstart;
            else
                app.TextArea.FontColor = [1.00,0.00,0.0];
                app.TextArea.Value = 'ERROR Bad Interval: Root is impossible to locate';
                status = false;
                return
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BrowseFileButton
        function BrowseFileButtonPushed(app, event)
            [filename,filepath] = uigetfile({'.txt'},'Search For the text file containing equations');
            fullname = [filepath filename];
            if ischar(fullname)
                if  isfile(fullname)
                    app.FiledirectoryEditField.Value = fullname;
                    fileID = fopen(fullname,'r');
                    data = textscan(fileID,'%s');
                    fclose(fileID);
                    stringData = string(data{:});
                    app.Equations = transpose(stringData);
                    t = "";
                    count = 0;
                    for i= app.Equations
                         if count ~= 0
                            t = strcat(t, ",");
                        end
                         t = strcat(t, i);
                         count = count + 1;
                    end
                    app.EquationEditField.Value = t;
                 else
                    app.TextArea.Value ="ERROR :file does not exist!!";
                end
            end
        end

        % Button pushed function: GoButton
        function GoButtonPushed(app, event)
            app.Equations = FormulateProcess(app);
            if isempty(app.Equations)
                app.TextArea.FontColor = [1.00,0.00,0.0];
                app.TextArea.Value = "ERROR : Enter Equation or browse for File";
            else
                app.TextArea.FontColor = [0.00,0.00,0.00];
                value = app.MethodSelectionDropDown.Value;
                if (strcmp(value,"Bisection"))
                    equ = app.EquationEditField.Value;
                    Bisection(app,equ,app.XLEditField.Value,app.XUEditField.Value,app.EpsilonEditField.Value,app.MaxiterationsEditField.Value);
                end
            end
        end

        % Value changed function: MethodSelectionDropDown
        function MethodSelectionDropDownValueChanged(app, event)
            value = app.MethodSelectionDropDown.Value;
            if (strcmp(value,"Bisection"))
                app.MethodSelectedPanel.Title = 'Bisection Method';
                app.XLEditField.Visible = true;
                app.XLEditFieldLabel.Visible = true;
                app.XLEditFieldLabel.Text = 'XL';
                app.XUEditField.Visible = true;
                app.XUEditFieldLabel.Visible = true;   
                app.XUEditFieldLabel.Text = 'XU';
                app.IntialGuessEditField.Visible = false;
                app.IntialGuessEditFieldLabel.Visible = false;
            elseif (strcmp(value,"False Postion"))
                app.MethodSelectedPanel.Title = 'False Postion Method';
                app.XLEditField.Visible = true;
                app.XLEditFieldLabel.Visible = true;
                app.XLEditFieldLabel.Text = 'XL';
                app.XUEditField.Visible = true;
                app.XUEditFieldLabel.Visible = true;
                 app.XUEditFieldLabel.Text = 'XU';
                app.IntialGuessEditField.Visible = false;
                app.IntialGuessEditFieldLabel.Visible = false;
            elseif (strcmp(value,"Fixed Point"))
                app.MethodSelectedPanel.Title = 'Fixed Point Method';
                app.IntialGuessEditField.Visible = true;
                app.IntialGuessEditFieldLabel.Visible = true;
                app.XLEditFieldLabel.Visible = false;
                app.XLEditField.Visible = false;
                app.XUEditFieldLabel.Visible = false;
                app.XUEditField.Visible = false;
            elseif (strcmp(value,"Newton-Raphson"))
                app.MethodSelectedPanel.Title = 'Newton-Raphson Method';
                app.XLEditFieldLabel.Text = 'X(i-1)';
                app.XLEditField.Visible = true;
                app.XLEditFieldLabel.Visible = true;
                app.XUEditFieldLabel.Text = 'X(i)';
                app.XUEditField.Visible = true;
                app.XUEditFieldLabel.Visible = true;
                app.IntialGuessEditField.Visible = false;
                app.IntialGuessEditFieldLabel.Visible = false;
            elseif (strcmp(value,"Secant"))
                app.MethodSelectedPanel.Title = 'Secant Method';
                app.XLEditFieldLabel.Text = 'X(i-1)';
                app.XLEditField.Visible = true;
                app.XLEditFieldLabel.Visible = true;
                app.XUEditFieldLabel.Text = 'X(i)';
                app.XUEditField.Visible = true;
                app.XUEditFieldLabel.Visible = true;
                app.IntialGuessEditField.Visible = false;
                app.IntialGuessEditFieldLabel.Visible = false;
            else
                app.MethodSelectedPanel.Title = 'Method Selected (None)';
                app.XLEditFieldLabel.Visible = false;
                app.XLEditField.Visible = false;
                app.XUEditFieldLabel.Visible = false;
                app.XUEditField.Visible = false;
                app.IntialGuessEditField.Visible = false;
                app.IntialGuessEditFieldLabel.Visible = false;
            end
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            app.TextArea.Value = "";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SolvingEquationusingNumericalMethodsLabel = uilabel(app.UIFigure);
            app.SolvingEquationusingNumericalMethodsLabel.Text = 'Solving Equation using Numerical Methods';
            app.BrowseFileButton = uibutton(app.UIFigure, 'push');
            app.BrowseFileButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseFileButtonPushed, true);
            app.BrowseFileButton.Text = 'Browse File';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Enter the required equation separated by comma of browse OR select file contating the equation !!';
            app.GoButton = uibutton(app.UIFigure, 'push');
            app.GoButton.ButtonPushedFcn = createCallbackFcn(app, @GoButtonPushed, true);
            app.GoButton.Text = 'Go';
            app.OutputScreenLabel = uilabel(app.UIFigure);
            app.OutputScreenLabel.Text = 'Output Screen';
            app.EquationEditFieldLabel = uilabel(app.UIFigure);
            app.EquationEditFieldLabel.Text = 'Equation';
            app.EquationEditField = uieditfield(app.UIFigure, 'text');
            app.MaxiterationsEditFieldLabel = uilabel(app.UIFigure);
            app.MaxiterationsEditFieldLabel.Text = 'Max iterations';
            app.MaxiterationsEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaxiterationsEditField.Value = 50;
            app.EpsilonEditFieldLabel = uilabel(app.UIFigure);
            app.EpsilonEditFieldLabel.Text = 'Epsilon';
            app.TextArea = uitextarea(app.UIFigure);
            app.EpsilonEditField = uieditfield(app.UIFigure, 'numeric');
            app.EpsilonEditField.Value = 1e-05;
            app.FiledirectoryEditFieldLabel = uilabel(app.UIFigure);
            app.FiledirectoryEditFieldLabel.Text = 'File directory';
            app.FiledirectoryEditField = uieditfield(app.UIFigure, 'text');
            app.MethodSelectionDropDownLabel = uilabel(app.UIFigure);
            app.MethodSelectionDropDownLabel.Text = 'Method Selection';
            app.MethodSelectionDropDown = uidropdown(app.UIFigure);
            app.MethodSelectionDropDown.Items = {'None', 'Bisection', 'False Postion', 'Fixed Point', 'Newton-Raphson', 'Secant'};
            app.MethodSelectionDropDown.ValueChangedFcn = createCallbackFcn(app, @MethodSelectionDropDownValueChanged, true);
            app.MethodSelectionDropDown.Value = 'None';
            app.MethodSelectedPanel = uipanel(app.UIFigure);
            app.MethodSelectedPanel.Title = 'Method Selected';
            app.XLEditFieldLabel = uilabel(app.MethodSelectedPanel);
            app.XLEditFieldLabel.Visible = 'off';
            app.XLEditFieldLabel.Text = 'XL';
            app.XLEditField = uieditfield(app.MethodSelectedPanel, 'numeric');
            app.XLEditField.Visible = 'off';
            app.XUEditFieldLabel = uilabel(app.MethodSelectedPanel);
            app.XUEditFieldLabel.Visible = 'off';
            app.XUEditFieldLabel.Text = 'XU';
            app.XUEditField = uieditfield(app.MethodSelectedPanel, 'numeric');
            app.XUEditField.Visible = 'off';
            app.IntialGuessEditFieldLabel = uilabel(app.MethodSelectedPanel);
            app.IntialGuessEditFieldLabel.Visible = 'off';
            app.IntialGuessEditFieldLabel.Text = 'Intial Guess';
            app.IntialGuessEditField = uieditfield(app.MethodSelectedPanel, 'text');
            app.IntialGuessEditField.Visible = 'off';
            app.ClearButton = uibutton(app.UIFigure, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end